classdef AddWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ADDButton                     matlab.ui.control.Button
        CAMERAIDEditFieldLabel        matlab.ui.control.Label
        CAMERAIDEditField             matlab.ui.control.EditField
        DETECTIONPERIODinminutesSpinnerLabel  matlab.ui.control.Label
        DETECTIONPERIODinminutesSpinner  matlab.ui.control.Spinner
        ASSIGNTOFACULTYDropDownLabel  matlab.ui.control.Label
        ASSIGNTOFACULTYDropDown       matlab.ui.control.DropDown
        LOCATIONPanel                 matlab.ui.container.Panel
        latitudeEditFieldLabel        matlab.ui.control.Label
        latitudeEditField             matlab.ui.control.EditField
        longitudeEditFieldLabel       matlab.ui.control.Label
        longitudeEditField            matlab.ui.control.EditField
        openmapButton                 matlab.ui.control.Button
        URLPanel                      matlab.ui.container.Panel
        USERNAMEEditFieldLabel        matlab.ui.control.Label
        USERNAMEEditField             matlab.ui.control.EditField
        PASSWORDEditFieldLabel        matlab.ui.control.Label
        PASSWORDEditField             matlab.ui.control.EditField
        ADRESSEditFieldLabel          matlab.ui.control.Label
        ADRESSEditField               matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp % Description
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
        
            app.CallingApp = mainapp;
            
            app.ASSIGNTOFACULTYDropDown.Items = getCustomers();
            
        end

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
            camName = app.CAMERAIDEditField.Value;
            period = app.DETECTIONPERIODinminutesSpinner.Value;
            period = period * 60;
            camUrl = app.ADRESSEditField.Value;
            urlPass = app.PASSWORDEditField.Value;
            urlUserName = app.USERNAMEEditField.Value;
            longitude = app.longitudeEditField.Value;
            latitude = app.latitudeEditField.Value;
            
            %{
            timer('Name',camName,...
                         'Period',period,... 
                         'BusyMode','queue',...
                         'UserData',camName,...
                         'ExecutionMode','fixedRate',...
                         'TimerFcn',@timerfcn); 
            
             %}
            %add camera to thingsboard and create required files locally
            
            addCamera(camName, camUrl, urlUserName, urlPass, period, longitude, latitude);
            assignDeviceToCustomer(camName, app.ASSIGNTOFACULTYDropDown.Value);
            updateTree(app.CallingApp,'camTab');
            updateTree(app.CallingApp, 'trainTab');
            delete(app);
        end

        % Button pushed function: openmapButton
        function openmapButtonPushed(app, event)
           geoplot(3.122500, 101.653038);
           geobasemap streets;
           geolimits([3.11944444 3.12500000],[101.64583333 101.66250000]);
           [lat, long]= ginput(200);
           lat = lat(end);
           long = long(end);
           
          app.latitudeEditField.Value = num2str(lat);
           app.longitudeEditField.Value = num2str(long);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ADDButton = uibutton(app.UIFigure, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.CAMERAIDEditFieldLabel = uilabel(app.UIFigure);
            app.CAMERAIDEditFieldLabel.Text = 'CAMERA ID';
            app.CAMERAIDEditField = uieditfield(app.UIFigure, 'text');
            app.DETECTIONPERIODinminutesSpinnerLabel = uilabel(app.UIFigure);
            app.DETECTIONPERIODinminutesSpinnerLabel.Text = 'DETECTION PERIOD (in minutes)';
            app.DETECTIONPERIODinminutesSpinner = uispinner(app.UIFigure);
            app.ASSIGNTOFACULTYDropDownLabel = uilabel(app.UIFigure);
            app.ASSIGNTOFACULTYDropDownLabel.Text = 'ASSIGN TO FACULTY';
            app.ASSIGNTOFACULTYDropDown = uidropdown(app.UIFigure);
            app.LOCATIONPanel = uipanel(app.UIFigure);
            app.LOCATIONPanel.Title = 'LOCATION';
            app.latitudeEditFieldLabel = uilabel(app.LOCATIONPanel);
            app.latitudeEditFieldLabel.Text = 'latitude';
            app.latitudeEditField = uieditfield(app.LOCATIONPanel, 'text');
            app.longitudeEditFieldLabel = uilabel(app.LOCATIONPanel);
            app.longitudeEditFieldLabel.Text = 'longitude';
            app.longitudeEditField = uieditfield(app.LOCATIONPanel, 'text');
            app.openmapButton = uibutton(app.LOCATIONPanel, 'push');
            app.openmapButton.ButtonPushedFcn = createCallbackFcn(app, @openmapButtonPushed, true);
            app.openmapButton.Text = 'open map';
            app.URLPanel = uipanel(app.UIFigure);
            app.URLPanel.Title = 'URL';
            app.USERNAMEEditFieldLabel = uilabel(app.URLPanel);
            app.USERNAMEEditFieldLabel.Text = 'USERNAME';
            app.USERNAMEEditField = uieditfield(app.URLPanel, 'text');
            app.PASSWORDEditFieldLabel = uilabel(app.URLPanel);
            app.PASSWORDEditFieldLabel.Text = 'PASSWORD';
            app.PASSWORDEditField = uieditfield(app.URLPanel, 'text');
            app.ADRESSEditFieldLabel = uilabel(app.URLPanel);
            app.ADRESSEditFieldLabel.Text = 'ADRESS';
            app.ADRESSEditField = uieditfield(app.URLPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end