classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ADDFACULTYUSERTab             matlab.ui.container.Tab
        ADDNEWORAGNIZATIONPanel       matlab.ui.container.Panel
        DETECTIONPERIODLABELt_2       matlab.ui.control.Label
        DETECTIONSTATELABELt_2        matlab.ui.control.Label
        NAMEEditFieldLabel            matlab.ui.control.Label
        NAMEEditField                 matlab.ui.control.EditField
        ADDORGANIZATIONButton         matlab.ui.control.Button
        AssigndashboardDropDownLabel  matlab.ui.control.Label
        AssigndashboardDropDown       matlab.ui.control.DropDown
        DELETEFACULTYUSERButton       matlab.ui.control.Button
        ADDNEWUSERPanel               matlab.ui.container.Panel
        DETECTIONSTATELABELt_3        matlab.ui.control.Label
        FIRSTNAMEEditFieldLabel       matlab.ui.control.Label
        FIRSTNAMEEditField            matlab.ui.control.EditField
        LASTNAMEEditFieldLabel        matlab.ui.control.Label
        LASTNAMEEditField             matlab.ui.control.EditField
        EMAILADDRESSEditFieldLabel    matlab.ui.control.Label
        EMAILADDRESSEditField         matlab.ui.control.EditField
        ADDUSERButton                 matlab.ui.control.Button
        UserTree                      matlab.ui.container.Tree
        ADDCAMERATab                  matlab.ui.container.Tab
        ADDButton                     matlab.ui.control.Button
        LOCATIONPanel                 matlab.ui.container.Panel
        latitudeEditFieldLabel        matlab.ui.control.Label
        latitudeEditField             matlab.ui.control.EditField
        longitudeEditFieldLabel       matlab.ui.control.Label
        longitudeEditField            matlab.ui.control.EditField
        openmapButton                 matlab.ui.control.Button
        URLPanel                      matlab.ui.container.Panel
        USERNAMEEditFieldLabel        matlab.ui.control.Label
        USERNAMEEditField             matlab.ui.control.EditField
        PASSWORDEditFieldLabel        matlab.ui.control.Label
        PASSWORDEditField             matlab.ui.control.EditField
        ADRESSEditFieldLabel          matlab.ui.control.Label
        ADRESSEditField               matlab.ui.control.EditField
        CAMERAIDEditFieldLabel        matlab.ui.control.Label
        CAMERAIDEditField             matlab.ui.control.EditField
        DETECTIONPERIODinminutesSpinnerLabel  matlab.ui.control.Label
        DETECTIONPERIODinminutesSpinner  matlab.ui.control.Spinner
        ASSIGNTOORGANIZATIONDropDownLabel  matlab.ui.control.Label
        ASSIGNTOORGANIZATIONDropDown  matlab.ui.control.DropDown
        CAMERADETAILSTab              matlab.ui.container.Tab
        MyTree                        matlab.ui.container.Tree
        CAMERADETAILSPanel            matlab.ui.container.Panel
        CAMERAIDLabel                 matlab.ui.control.Label
        CAMERAIDLABELt                matlab.ui.control.Label
        DETECTIONPERIODLabel          matlab.ui.control.Label
        ORGANIZATIONLabel             matlab.ui.control.Label
        CAMERAURLLabel                matlab.ui.control.Label
        CAMERAURLLabelt               matlab.ui.control.Label
        FACULTYLabelt                 matlab.ui.control.Label
        DETECTIONPERIODLABELt         matlab.ui.control.Label
        DETECTIONSTATELabel           matlab.ui.control.Label
        DETECTIONSTATELABELt          matlab.ui.control.Label
        TRAINEDLabel                  matlab.ui.control.Label
        TRAINEDLABELt                 matlab.ui.control.Label
        DELETECAMERAButton            matlab.ui.control.Button
        TRAINCAMERATab                matlab.ui.container.Tab
        CaptureimageButton            matlab.ui.control.Button
        SplitimagefortrainingButton   matlab.ui.control.Button
        StartCNNtrainingButton        matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        MyTree_2                      matlab.ui.container.Tree
        PLEASEWAITLabel               matlab.ui.control.Label
        COMPLETEDLabel                matlab.ui.control.Label
        DETECTIONTab                  matlab.ui.container.Tab
        CAMERAListBoxLabel            matlab.ui.control.Label
        CAMERAListBox                 matlab.ui.control.ListBox
        FACULTYListBoxLabel           matlab.ui.control.Label
        FACULTYListBox                matlab.ui.control.ListBox
        StartdetectionButton          matlab.ui.control.Button
        StopdetectionButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        AddWindowApp
    end
    
    properties (Access = public)
        token       %JWT access token for the admin
        signedUser  %name of the admin according to Thingsboard.
    end
    
    
    methods (Access = public)
        
        function updateTree(app,tree)
                        
            if strcmp(tree,'camTab') 
                treeType = app.MyTree;
              
            elseif strcmp(tree,'trainTab')
                treeType = app.MyTree_2;
                
            
            end
         
                        
            delete(treeType.Children);
            faculties = getCustomers(app.token);
         
            for i = 1: length(faculties)
            
                t = uitreenode(treeType,"Text",faculties{i});
                try
                    devices = getCustomerDevices(faculties{i},app.token);
                
                    for i = 1: length(devices)
                
                        uitreenode(t, "Text",devices{i});
                    end
                catch
                end
            end

        end
        
        function updateUserTree(app)
            delete(app.UserTree.Children);
            faculties = getCustomers(app.token);
            
           for i = 1: length(faculties)
             
            
                t = uitreenode(app.UserTree,"Text",faculties{i});
                try
                    users = getCustomerUsers(faculties{i},app.token);
                
                    for i = 1: length(users)
                
                        uitreenode(t, "Text",users{i});
                    end
                catch

                end
           end
            
            
        end
    end
    
    methods (Access = private)
        
        function updateFaculties(app)
            faculties = getCustomers(app.token);
            app.FACULTYListBox.Items = faculties;
            app.ASSIGNTOORGANIZATIONDropDown.Items = faculties;
        end
        
        function updateDashboards(app)
            dashboards = getDashboards(app.token)
            app.AssigndashboardDropDown.Items = dashboards;
            
            
        end
      
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, token)
          app.token = token
           app.signedUser = getSignedUser(token);
            
            disp(app.token);
           disp(app.signedUser)
            
           % uitreenode(app.MyTree,"Text","New");
            updateFaculties(app);
            updateTree(app,'camTab');
            updateTree(app, 'trainTab');
            updateUserTree(app);
            updateDashboards(app);
            app.CAMERAIDLabel.Visible = 'off';
            app.CAMERAURLLabel.Visible = 'off';
            app.DETECTIONPERIODLabel.Visible = 'off';
            app.ORGANIZATIONLabel.Visible = 'off';
            app.UIAxes.Visible = 'off';
            app.DETECTIONSTATELabel.Visible = 'off';
            app.COMPLETEDLabel.Visible = 'off';
            app.PLEASEWAITLabel.Visible = 'off';
            app.TRAINEDLabel.Visible = 'off';


        end

        % Value changed function: FACULTYListBox
        function FACULTYListBoxValueChanged(app, event)
            value = app.FACULTYListBox.Value;
            try
            app.CAMERAListBox.Items = getCustomerDevices(value,app.token);
            catch
            end
        end

        % Button pushed function: StartdetectionButton
        function StartdetectionButtonPushed(app, event)
            try
            camName = app.CAMERAListBox.Value;
            period = getDeviceAtt(camName, 'PERIOD',app.token);
            period = period *60;


            if(isempty(timerfind("Name", camName)))
            timer('Name',camName,...
                         'Period',period,...
                         "UserData",{camName, app.token, app.signedUser},...
                         'BusyMode','queue',...
                         'ExecutionMode','fixedRate',...
                         'TimerFcn',@timerfcn); 
            
            else
            disp('timer in this name is found')
            end
        
            str = timerfind('Name', camName).Running;
                if(strcmp(str, 'off'))
                   
                    start(timerfind('Name', camName));
               
                end


        CAMERAListBoxValueChanged(app, event);
            catch ME
                if(ME.message == 'Function is not defined for ''cell'' inputs.')
                    uialert(app.UIFigure,'Please selecte a camera','Error',"Icon","error")
                end
            end
                     

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            app.COMPLETEDLabel.Visible = 'off';
            %startupFcn(app);
            
            
        end

        % Callback function
        function FACULTYListBox_camTabValueChanged(app, event)
            value = app.FACULTYListBox_camTab.Value;
            app.CAMERAListBox_camTab.Items = getCustomerDevices(value);
        end

        % Callback function
        function ADDCAMERAButtonPushed(app, event)
                app.AddWindowApp = AddWindow(app);
        end

        % Selection changed function: MyTree
        function MyTreeSelectionChanged(app, event)
            selectedNodes = app.MyTree.SelectedNodes;
            
           
            %check if selected node is a camera
            
            if (~(selectedNodes.Parent == app.MyTree))
                
                name = selectedNodes.Text;              
                period = getDeviceAtt(name, 'PERIOD',app.token);     
                url = getDeviceAtt (name, 'URL',app.token);
                
                
                %set labels visible
                
                app.CAMERAIDLabel.Visible = 'on';
                app.CAMERAURLLabel.Visible = 'on';
                app.DETECTIONPERIODLabel.Visible = 'on';
                app.ORGANIZATIONLabel.Visible = 'on';
                app.DETECTIONSTATELabel.Visible = 'on';
                app.TRAINEDLabel.Visible = 'on';
                %show texts on labels
                
                app.CAMERAIDLABELt.Text = name;
                
                app.CAMERAURLLabelt.Text = num2str(url);
                
                app.DETECTIONPERIODLABELt.Text = num2str(period);
                app.FACULTYLabelt.Text  = selectedNodes.Parent.Text;
                
                if(isempty(timerfind('Name',name)))
                    detectionState = 'Off';
                else
                    detectionState = timerfind("Name",name).Running;
                end
                app.DETECTIONSTATELABELt.Text = detectionState;
                
                featureFile = fullfile("Cameras",app.signedUser,name,'trainingFeatures2.mat');
                setFile = fullfile("Cameras",app.signedUser,name,'trainingSet2.mat');

                if(isfile(featureFile)&& isfile(setFile))
                    app.TRAINEDLABELt.Text = 'YES';
                else
                    app.TRAINEDLABELt.Text = 'NO';
                end
            end
            
        end

        % Button pushed function: StopdetectionButton
        function StopdetectionButtonPushed(app, event)
            
            camName = app.CAMERAListBox.Value;
            
            if (~isempty(timerfind('Name',camName)))
                stop (timerfind('Name', camName))
            end
            
            CAMERAListBoxValueChanged(app, event);
            
        end

        % Value changed function: CAMERAListBox
        function CAMERAListBoxValueChanged(app, event)
            value = app.CAMERAListBox.Value;
            %{
            if(~isempty(timerfind('Name', value)))
                  tmr = timerfind('Name', value).Running;
                  if(strcmp(tmr, 'off'))
                    app.StartdetectionButton.Enable = 'on';
                    app.StopdetectionButton.Enable = 'off';
                  else
                     app.StartdetectionButton.Enable = 'off';
                     app.StopdetectionButton.Enable = 'on';
                  end
            else
                app.StartdetectionButton.Enable = 'on';
                app.StopdetectionButton.Enable = 'off';
                    
            end
            %}


        end

        % Button pushed function: CaptureimageButton
        function CaptureimageButtonPushed(app, event)
            
            camName ='';
            %try to get camName
            try
                camName = app.MyTree_2.SelectedNodes.Text;
            catch 
            end
            
            %check if the node is camera
            
            if(isempty(camName) || (app.MyTree_2.SelectedNodes.Parent ==app.MyTree_2))
                uialert(app.UIFigure,'Selecte camera','Warning',"Icon","warning")
            else
                try
                    myImg = captureImg(camName,app.token);
                     %save Image to file with date and time information
                    time = datestr(now,30);
                    
                    photoDir = sprintf('Cameras/%s/%s/NewData/%s.jpg', app.signedUser,camName, time ); %set the directory the image will be saved to
                    imwrite(myImg, photoDir, 'jpg');
                    I = imread(photoDir,"jpg");
                    app.UIAxes.Visible ='on';
                    imshow(myImg, 'Parent', app.UIAxes);
                catch
                   uialert(app.UIFigure,'Unable to capture image','error',"Icon","error");
                end


            end


        end

        % Button pushed function: DELETECAMERAButton
        function DELETECAMERAButtonPushed(app, event)
            selectedCam = app.MyTree.SelectedNodes;
            
            
            if(~(selectedCam.Parent == app.MyTree))
            try
            %delete camera from thingsboard
            deleteCamera(selectedCam.Text,app.token);
            
            camDir = fullfile('Cameras',app.signedUser, selectedCam.Text);
            rmdir(camDir,"s");
            updateTree(app,'trainTab');
            updateTree(app, 'camTab');
            
            %delete timer object if exists
            
             try
               delete(timerfind("Name",selectedCam.Text))
             catch 
                    
             end
            
            uialert(app.UIFigure,'Camera deleted','Success',"Icon","success");


            catch 
                
            uialert(app.UIFigure,'Unable to delete camera','Error',"Icon","error");

            end
            else
            uialert(app.UIFigure,'Select a camera','Error',"Icon","error");

            end
        end

        % Button pushed function: SplitimagefortrainingButton
        function SplitimagefortrainingButtonPushed(app, event)
           
            
            camName ='';
            %check if a node selected (any node)
            try
                camName = app.MyTree_2.SelectedNodes.Text;
            catch 
 
            end
            
            
             if(isempty(camName) || (app.MyTree_2.SelectedNodes.Parent ==app.MyTree_2))
                uialert(app.UIFigure,'Selecte camera','Warning',"Icon","warning")
             else


                    [filename pathname] = uigetfile({'*.jpg';'*.png'},'File Selector');
                     image = strcat(pathname, filename);
            
                     camName = app.MyTree_2.SelectedNodes.Text;
                     splitImage (camName, filename, image,app.signedUser);


            end
            
            %{
           try 
               
            if(~(app.MyTree_2.SelectedNodes.parent == app.MyTree_2))


            uialert(app.UIFigure,'Select a camera','Error',"Icon","warning");
               
            else
               uialert(app.UIFigure,'Select a camera1','Error',"Icon","warning");

            end
           catch ME
              % if(ME.identifier =='MATLAB:undefinedVarOrClass')
                    uialert(app.UIFigure,'Select a camera2','Error',"Icon","error");
               %end
           end
            %}


        end

        % Button pushed function: StartCNNtrainingButton
        function StartCNNtrainingButtonPushed(app, event)
            
            
            selectedNodes = app.MyTree_2.SelectedNodes;
            %check if selected node is a camera
           try
            if (~(selectedNodes.Parent == app.MyTree_2))
                app.PLEASEWAITLabel.Visible = 'on';
                drawnow;
                
                name = selectedNodes.Text;
                try
                trainFeatures(name,app.signedUser);
                catch ME
                    if (ME.identifier == 'MATLAB:datastoreio:pathlookup:emptyFolder')
                        uialert(app.UIFigure,'Please split images for training!','Error',"Icon","error")
                    end
                end
                app.COMPLETEDLabel.Visible = 'on';
                app.PLEASEWAITLabel.Visible = 'off';
            else
                uialert(app.UIFigure,'Please select camera',"Warning","Icon","warning")
            end
            
           catch ME
                if(ME.identifier =='MATLAB:structRefFromNonStruct')
                    uialert(app.UIFigure,'Please select a camera','Error',"Icon","warning")
                end

                    
            end


        end

        % Button pushed function: ADDORGANIZATIONButton
        function ADDORGANIZATIONButtonPushed(app, event)
            name = app.NAMEEditField.Value;
            if(isempty(name))
                uialert(app.UIFigure,'Enter a valid name','Error',"Icon","error");
            else
                
            try    
            addCustomer(name,app.token);
            assignDashboardToCustomer(name, app.AssigndashboardDropDown.Value,app.token);
            updateUserTree(app);
            updateTree(app,'camTab');
            updateTree(app,'trainTab');
            updateFaculties(app);
            app.NAMEEditField.Value ='';
            
            uialert(app.UIFigure,'Organazation added','Success',"Icon","success");
            
            catch
              uialert(app.UIFigure,'Enter a valid name','Success',"Icon","error");

            end
            end
            
 
        end

        % Button pushed function: DELETEFACULTYUSERButton
        function DELETEFACULTYUSERButtonPushed(app, event)
            
            selectedNodes = app.UserTree.SelectedNodes;

            
            if(~(selectedNodes.Parent == app.UserTree))
                
                facultyName = app.UserTree.SelectedNodes.Parent.Text;
                C = strsplit(selectedNodes.Text);
                firstName = C{1}
                lastName=C{2}
                userId = getUserIdByName(facultyName,firstName, lastName,app.token);
                deleteUserById(userId,app.token);
                updateUserTree(app);
                uialert(app.UIFigure,'User deleted','Success',"Icon","success");
                
                
            else
                
                deleteCustomer(app.UserTree.SelectedNodes.Text,app.token);
                updateTree(app,'camTab');
                updateTree(app,'trainTab');
                updateUserTree(app);
                uialert(app.UIFigure,'Organization deleted','Success',"Icon","success");
                updateFaculties(app);

            end


        end

        % Button pushed function: ADDUSERButton
        function ADDUSERButtonPushed(app, event)
            
            try
            facultyName = app.UserTree.SelectedNodes.Text;
            firstName = strrep(app.FIRSTNAMEEditField.Value,' ','');
            lastName = strrep(app.LASTNAMEEditField.Value,' ','');
            email = app.EMAILADDRESSEditField.Value;
           %{ 
            idx = strfind(email,'.');
            domain ='';
            if ~isempty(idx)
                 domain = email(idx+1:end);
            end
            %}
            
            
             if(isempty(firstName) ||isempty(lastName)|| isempty(email))
                uialert(app.UIFigure,'Please fill all fields','Error',"Icon","error");
                
             else
                 
                    try
                        addUser(facultyName,email,firstName,lastName,app.token);
                        updateUserTree(app);
                        app.FIRSTNAMEEditField.Value = '';
                        app.LASTNAMEEditField.Value = '';
                        app.EMAILADDRESSEditField.Value = '';
                        uialert(app.UIFigure,'User added','Success',"Icon","success");
                    catch
                         uialert(app.UIFigure,'Error in adding the user!','Error',"Icon","error");
                         app.FIRSTNAMEEditField.Value = '';
                         app.LASTNAMEEditField.Value = '';
                         app.EMAILADDRESSEditField.Value = '';
                    end
                    
              end


            catch ME
                if (strcmp(ME.message,'Dot indexing is not supported for variables of this type.') || ~(app.UserTree.SelectedNodes.Parent == app.UserTree))
                   uialert(app.UIFigure,'Select an organization','Error',"Icon","error");
                else
                   uialert(app.UIFigure,'Error',ME.message,"Icon","error");
                end

                    
            end


        end

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
            camName = app.CAMERAIDEditField.Value;
            period = app.DETECTIONPERIODinminutesSpinner.Value;
            period = period;
            camUrl = app.ADRESSEditField.Value;
            urlPass = app.PASSWORDEditField.Value;
            urlUserName = app.USERNAMEEditField.Value;
            longitude = app.longitudeEditField.Value;
            latitude = app.latitudeEditField.Value;
            
            if(isempty(camName) || period <0 || period ==0 || isempty(camUrl) || isempty(longitude) || isempty(latitude))
                uialert(app.UIFigure,'Enter valid values','Error',"Icon","error");
            else
                try
                addCamera(camName, camUrl, urlUserName, urlPass, period, longitude, latitude,app.token,app.signedUser);
                assignDeviceToCustomer(camName, app.ASSIGNTOORGANIZATIONDropDown.Value,app.token);
                updateTree(app,'camTab');
                updateTree(app, 'trainTab');
                
                app.CAMERAIDEditField.Value = '';
                app.DETECTIONPERIODinminutesSpinner.Value = 0;
                app.ADRESSEditField.Value ='';
                app.PASSWORDEditField.Value = '';
                app.USERNAMEEditField.Value ='';
                app.longitudeEditField.Value = '';
                app.latitudeEditField.Value ='';
                uialert(app.UIFigure,'Camera added','success',"Icon","success");
                catch
                    uialert(app.UIFigure,'unable to add camera', 'Error',"Icon","error")
                end
            end
            
           
        end

        % Button pushed function: openmapButton
        function openmapButtonPushed(app, event)
           geoplot(3.122500, 101.653038);
           geobasemap streets;
           geolimits([3.11944444 3.12500000],[101.64583333 101.66250000]);
           [lat, long]= ginput(200);
           lat = lat(end);
           long = long(end);
           
          app.latitudeEditField.Value = num2str(lat);
           app.longitudeEditField.Value = num2str(long);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ADDFACULTYUSERTab = uitab(app.TabGroup);
            app.ADDFACULTYUSERTab.Tooltip = {''; ''};
            app.ADDFACULTYUSERTab.Title = 'ADD FACULTY/USER';
            app.ADDNEWORAGNIZATIONPanel = uipanel(app.ADDFACULTYUSERTab);
            app.ADDNEWORAGNIZATIONPanel.Title = 'ADD NEW ORAGNIZATION';
            app.DETECTIONPERIODLABELt_2 = uilabel(app.ADDNEWORAGNIZATIONPanel);
            app.DETECTIONPERIODLABELt_2.Text = '';
            app.DETECTIONSTATELABELt_2 = uilabel(app.ADDNEWORAGNIZATIONPanel);
            app.DETECTIONSTATELABELt_2.Text = '';
            app.NAMEEditFieldLabel = uilabel(app.ADDNEWORAGNIZATIONPanel);
            app.NAMEEditFieldLabel.Text = ' NAME';
            app.NAMEEditField = uieditfield(app.ADDNEWORAGNIZATIONPanel, 'text');
            app.ADDORGANIZATIONButton = uibutton(app.ADDNEWORAGNIZATIONPanel, 'push');
            app.ADDORGANIZATIONButton.ButtonPushedFcn = createCallbackFcn(app, @ADDORGANIZATIONButtonPushed, true);
            app.ADDORGANIZATIONButton.Text = 'ADD ORGANIZATION';
            app.AssigndashboardDropDownLabel = uilabel(app.ADDNEWORAGNIZATIONPanel);
            app.AssigndashboardDropDownLabel.Text = 'Assign dashboard';
            app.AssigndashboardDropDown = uidropdown(app.ADDNEWORAGNIZATIONPanel);
            app.DELETEFACULTYUSERButton = uibutton(app.ADDFACULTYUSERTab, 'push');
            app.DELETEFACULTYUSERButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEFACULTYUSERButtonPushed, true);
            app.DELETEFACULTYUSERButton.Text = 'DELETE FACULTY/USER';
            app.ADDNEWUSERPanel = uipanel(app.ADDFACULTYUSERTab);
            app.ADDNEWUSERPanel.Title = 'ADD NEW USER';
            app.DETECTIONSTATELABELt_3 = uilabel(app.ADDNEWUSERPanel);
            app.DETECTIONSTATELABELt_3.Text = '';
            app.FIRSTNAMEEditFieldLabel = uilabel(app.ADDNEWUSERPanel);
            app.FIRSTNAMEEditFieldLabel.Text = 'FIRST NAME';
            app.FIRSTNAMEEditField = uieditfield(app.ADDNEWUSERPanel, 'text');
            app.LASTNAMEEditFieldLabel = uilabel(app.ADDNEWUSERPanel);
            app.LASTNAMEEditFieldLabel.Text = 'LAST NAME';
            app.LASTNAMEEditField = uieditfield(app.ADDNEWUSERPanel, 'text');
            app.EMAILADDRESSEditFieldLabel = uilabel(app.ADDNEWUSERPanel);
            app.EMAILADDRESSEditFieldLabel.Text = 'EMAIL ADDRESS';
            app.EMAILADDRESSEditField = uieditfield(app.ADDNEWUSERPanel, 'text');
            app.ADDUSERButton = uibutton(app.ADDNEWUSERPanel, 'push');
            app.ADDUSERButton.ButtonPushedFcn = createCallbackFcn(app, @ADDUSERButtonPushed, true);
            app.ADDUSERButton.Text = 'ADD USER';
            app.UserTree = uitree(app.ADDFACULTYUSERTab);
            app.ADDCAMERATab = uitab(app.TabGroup);
            app.ADDCAMERATab.Tooltip = {''; ''};
            app.ADDCAMERATab.Title = 'ADD CAMERA';
            app.ADDButton = uibutton(app.ADDCAMERATab, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.LOCATIONPanel = uipanel(app.ADDCAMERATab);
            app.LOCATIONPanel.Title = 'LOCATION';
            app.latitudeEditFieldLabel = uilabel(app.LOCATIONPanel);
            app.latitudeEditFieldLabel.Text = 'latitude';
            app.latitudeEditField = uieditfield(app.LOCATIONPanel, 'text');
            app.longitudeEditFieldLabel = uilabel(app.LOCATIONPanel);
            app.longitudeEditFieldLabel.Text = 'longitude';
            app.longitudeEditField = uieditfield(app.LOCATIONPanel, 'text');
            app.openmapButton = uibutton(app.LOCATIONPanel, 'push');
            app.openmapButton.ButtonPushedFcn = createCallbackFcn(app, @openmapButtonPushed, true);
            app.openmapButton.Text = 'open map';
            app.URLPanel = uipanel(app.ADDCAMERATab);
            app.URLPanel.Title = 'URL';
            app.USERNAMEEditFieldLabel = uilabel(app.URLPanel);
            app.USERNAMEEditFieldLabel.Text = 'USERNAME';
            app.USERNAMEEditField = uieditfield(app.URLPanel, 'text');
            app.PASSWORDEditFieldLabel = uilabel(app.URLPanel);
            app.PASSWORDEditFieldLabel.Text = 'PASSWORD';
            app.PASSWORDEditField = uieditfield(app.URLPanel, 'text');
            app.ADRESSEditFieldLabel = uilabel(app.URLPanel);
            app.ADRESSEditFieldLabel.Text = 'ADRESS';
            app.ADRESSEditField = uieditfield(app.URLPanel, 'text');
            app.CAMERAIDEditFieldLabel = uilabel(app.ADDCAMERATab);
            app.CAMERAIDEditFieldLabel.Text = 'CAMERA ID';
            app.CAMERAIDEditField = uieditfield(app.ADDCAMERATab, 'text');
            app.DETECTIONPERIODinminutesSpinnerLabel = uilabel(app.ADDCAMERATab);
            app.DETECTIONPERIODinminutesSpinnerLabel.Text = 'DETECTION PERIOD (in minutes)';
            app.DETECTIONPERIODinminutesSpinner = uispinner(app.ADDCAMERATab);
            app.ASSIGNTOORGANIZATIONDropDownLabel = uilabel(app.ADDCAMERATab);
            app.ASSIGNTOORGANIZATIONDropDownLabel.Text = 'ASSIGN TO ORGANIZATION';
            app.ASSIGNTOORGANIZATIONDropDown = uidropdown(app.ADDCAMERATab);
            app.CAMERADETAILSTab = uitab(app.TabGroup);
            app.CAMERADETAILSTab.Tooltip = {''; ''};
            app.CAMERADETAILSTab.Title = 'CAMERA DETAILS';
            app.MyTree = uitree(app.CAMERADETAILSTab);
            app.MyTree.SelectionChangedFcn = createCallbackFcn(app, @MyTreeSelectionChanged, true);
            app.CAMERADETAILSPanel = uipanel(app.CAMERADETAILSTab);
            app.CAMERADETAILSPanel.Title = 'CAMERA DETAILS';
            app.CAMERAIDLabel = uilabel(app.CAMERADETAILSPanel);
            app.CAMERAIDLabel.Text = 'CAMERA ID:';
            app.CAMERAIDLABELt = uilabel(app.CAMERADETAILSPanel);
            app.CAMERAIDLABELt.Text = '';
            app.DETECTIONPERIODLabel = uilabel(app.CAMERADETAILSPanel);
            app.DETECTIONPERIODLabel.Text = 'DETECTION PERIOD:';
            app.ORGANIZATIONLabel = uilabel(app.CAMERADETAILSPanel);
            app.ORGANIZATIONLabel.Text = 'ORGANIZATION:';
            app.CAMERAURLLabel = uilabel(app.CAMERADETAILSPanel);
            app.CAMERAURLLabel.Text = {'CAMERA URL:'; ''};
            app.CAMERAURLLabelt = uilabel(app.CAMERADETAILSPanel);
            app.CAMERAURLLabelt.Text = '';
            app.FACULTYLabelt = uilabel(app.CAMERADETAILSPanel);
            app.FACULTYLabelt.Text = '';
            app.DETECTIONPERIODLABELt = uilabel(app.CAMERADETAILSPanel);
            app.DETECTIONPERIODLABELt.Text = '';
            app.DETECTIONSTATELabel = uilabel(app.CAMERADETAILSPanel);
            app.DETECTIONSTATELabel.Text = 'DETECTION STATE:';
            app.DETECTIONSTATELABELt = uilabel(app.CAMERADETAILSPanel);
            app.DETECTIONSTATELABELt.Text = '';
            app.TRAINEDLabel = uilabel(app.CAMERADETAILSPanel);
            app.TRAINEDLabel.Text = 'TRAINED:';
            app.TRAINEDLABELt = uilabel(app.CAMERADETAILSPanel);
            app.TRAINEDLABELt.Text = '';
            app.DELETECAMERAButton = uibutton(app.CAMERADETAILSTab, 'push');
            app.DELETECAMERAButton.ButtonPushedFcn = createCallbackFcn(app, @DELETECAMERAButtonPushed, true);
            app.DELETECAMERAButton.Text = 'DELETE CAMERA';
            app.TRAINCAMERATab = uitab(app.TabGroup);
            app.TRAINCAMERATab.Title = 'TRAIN CAMERA';
            app.CaptureimageButton = uibutton(app.TRAINCAMERATab, 'push');
            app.CaptureimageButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureimageButtonPushed, true);
            app.CaptureimageButton.Text = 'Capture image';
            app.SplitimagefortrainingButton = uibutton(app.TRAINCAMERATab, 'push');
            app.SplitimagefortrainingButton.ButtonPushedFcn = createCallbackFcn(app, @SplitimagefortrainingButtonPushed, true);
            app.SplitimagefortrainingButton.Text = 'Split image for training';
            app.StartCNNtrainingButton = uibutton(app.TRAINCAMERATab, 'push');
            app.StartCNNtrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartCNNtrainingButtonPushed, true);
            app.StartCNNtrainingButton.Text = 'Start CNN training';
            app.UIAxes = uiaxes(app.TRAINCAMERATab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.MyTree_2 = uitree(app.TRAINCAMERATab);
            app.PLEASEWAITLabel = uilabel(app.TRAINCAMERATab);
            app.PLEASEWAITLabel.Text = {'PLEASE WAIT..'; ''};
            app.COMPLETEDLabel = uilabel(app.TRAINCAMERATab);
            app.COMPLETEDLabel.Text = {'COMPLETED!'; ''};
            app.DETECTIONTab = uitab(app.TabGroup);
            app.DETECTIONTab.Title = 'DETECTION';
            app.CAMERAListBoxLabel = uilabel(app.DETECTIONTab);
            app.CAMERAListBoxLabel.Text = {'CAMERA'; ''};
            app.CAMERAListBox = uilistbox(app.DETECTIONTab);
            app.CAMERAListBox.Items = {};
            app.CAMERAListBox.ValueChangedFcn = createCallbackFcn(app, @CAMERAListBoxValueChanged, true);
            app.CAMERAListBox.Value = {};
            app.FACULTYListBoxLabel = uilabel(app.DETECTIONTab);
            app.FACULTYListBoxLabel.Text = {'FACULTY'; ''; ''; ''; ''};
            app.FACULTYListBox = uilistbox(app.DETECTIONTab);
            app.FACULTYListBox.Items = {};
            app.FACULTYListBox.ValueChangedFcn = createCallbackFcn(app, @FACULTYListBoxValueChanged, true);
            app.FACULTYListBox.Value = {};
            app.StartdetectionButton = uibutton(app.DETECTIONTab, 'push');
            app.StartdetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartdetectionButtonPushed, true);
            app.StartdetectionButton.Text = 'Start detection';
            app.StopdetectionButton = uibutton(app.DETECTIONTab, 'push');
            app.StopdetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StopdetectionButtonPushed, true);
            app.StopdetectionButton.Text = 'Stop detection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end